classdef RIFF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StartWorkflowButton             matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        NextButton                      matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        StopExecutionButton             matlab.ui.control.StateButton
        RefreshButton                   matlab.ui.control.Button
        PreviewBodeplotButton           matlab.ui.control.Button
        FITButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        INPUT_DATA_FORMATDropDown       matlab.ui.control.DropDown
        INPUT_DATA_FORMATDropDownLabel  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        TextFileTab                     matlab.ui.container.Tab
        FORMATDropDown                  matlab.ui.control.DropDown
        FORMATDropDownLabel             matlab.ui.control.Label
        UploadnewfileButton             matlab.ui.control.Button
        ChooseTFListBox                 matlab.ui.control.ListBox
        WorkSpaceTab                    matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        TimeSeriesTab                   matlab.ui.container.Tab
        BWTextArea                      matlab.ui.control.TextArea
        BWTextAreaLabel                 matlab.ui.control.Label
        Output_Sampling_FrequencyTextArea  matlab.ui.control.TextArea
        Output_Sampling_FrequencyTextAreaLabel  matlab.ui.control.Label
        Input_Sampling_FrequencyTextArea  matlab.ui.control.TextArea
        Input_Sampling_FrequencyTextAreaLabel  matlab.ui.control.Label
        Output_TimeSeriesTextArea       matlab.ui.control.TextArea
        Output_TimeSeriesTextAreaLabel  matlab.ui.control.Label
        SpecifythecorrespondingworkspacevariablenamesLabel  matlab.ui.control.Label
        Input_TimeSeriesTextArea        matlab.ui.control.TextArea
        Input_TimeSeriesTextAreaLabel   matlab.ui.control.Label
        FRDTab                          matlab.ui.container.Tab
        MATLABFrequencyResponseDataLabel  matlab.ui.control.Label
        FRDTextArea                     matlab.ui.control.TextArea
        FRDTextAreaLabel                matlab.ui.control.Label
        SpecifythecorrespondingworkspacevariablenameLabel  matlab.ui.control.Label
        FTFTab                          matlab.ui.container.Tab
        FrequencyHzComplexTransferFunctionLabel  matlab.ui.control.Label
        TFTextArea                      matlab.ui.control.TextArea
        TFTextAreaLabel                 matlab.ui.control.Label
        FTextArea                       matlab.ui.control.TextArea
        FTextAreaLabel                  matlab.ui.control.Label
        SpecifythecorrespondingworkspacevariablenamesLabel_2  matlab.ui.control.Label
        LTPDAAOTab                      matlab.ui.container.Tab
        LTPDAAnalysisObjectLabel        matlab.ui.control.Label
        SpecifythecorrespondingworkspacevariablenameLabel_2  matlab.ui.control.Label
        AOTextArea                      matlab.ui.control.TextArea
        AOTextAreaLabel                 matlab.ui.control.Label
        SpectrumAnalyzerTab             matlab.ui.container.Tab
        IfmeasurementsspanacrossdifferentfilesusemultiselectLabel  matlab.ui.control.Label
        HPAgilentKeysightSDFDATformatLabel  matlab.ui.control.Label
        ChooseTFListBox_2               matlab.ui.control.ListBox
        UploadnewfileButton_2           matlab.ui.control.Button
        FreqTab                         matlab.ui.container.Tab
        whenMethod7Label                matlab.ui.control.Label
        MethodLabel                     matlab.ui.control.Label
        SMOOTH_WIN_LENSlider            matlab.ui.control.Slider
        SMOOTH_WIN_LENSliderLabel       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        SMOOTH_LEVELEditField           matlab.ui.control.NumericEditField
        SMOOTH_LEVELEditFieldLabel      matlab.ui.control.Label
        FIT_NOISE_FLOORCheckBox         matlab.ui.control.CheckBox
        F_FINALEditFieldLabel           matlab.ui.control.Label
        F_FINALEditField                matlab.ui.control.NumericEditField
        F_FINAL_toggleCheckBox          matlab.ui.control.CheckBox
        F_INITIAL_toggleCheckBox        matlab.ui.control.CheckBox
        F_INITIALEditFieldLabel         matlab.ui.control.Label
        F_INITIALEditField              matlab.ui.control.NumericEditField
        FittingTab                      matlab.ui.container.Tab
        Tree                            matlab.ui.container.CheckBoxTree
        FITTING_ROUTINESNode            matlab.ui.container.TreeNode
        rationalfitNode                 matlab.ui.container.TreeNode
        tfestNode                       matlab.ui.container.TreeNode
        invfreqsNode                    matlab.ui.container.TreeNode
        fitmagfrdNode                   matlab.ui.container.TreeNode
        AAANode                         matlab.ui.container.TreeNode
        INCLUDE_IODELAYCheckBox         matlab.ui.control.CheckBox
        FIT_WEIGHT_FROM_PHASE_SMOOTHNESSCheckBox  matlab.ui.control.CheckBox
        NUM_ZEROSEditField              matlab.ui.control.NumericEditField
        NUM_ZEROSEditFieldLabel         matlab.ui.control.Label
        ENFORCE_MINIMUM_PHASECheckBox   matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        ENFORCE_STABILITYCheckBox       matlab.ui.control.CheckBox
        ESTIMATE_UNCERTAINITYCheckBox   matlab.ui.control.CheckBox
        DESIRED_GOFEditField            matlab.ui.control.NumericEditField
        DESIRED_GOFEditFieldLabel       matlab.ui.control.Label
        NUM_TRIALSEditField             matlab.ui.control.NumericEditField
        NUM_TRIALSEditFieldLabel        matlab.ui.control.Label
        MAX_POLESEditField              matlab.ui.control.NumericEditField
        MAX_POLESEditFieldLabel         matlab.ui.control.Label
        MIN_POLESEditField              matlab.ui.control.NumericEditField
        MIN_POLESEditFieldLabel         matlab.ui.control.Label
        ATTAIN_GOFCheckBox              matlab.ui.control.CheckBox
        OPTIMIZERDropDownLabel          matlab.ui.control.Label
        OPTIMIZERDropDown               matlab.ui.control.DropDown
        ResultsTab                      matlab.ui.container.Tab
        EXPORT_VARIABLE_NAMEEditField   matlab.ui.control.EditField
        EXPORT_VARIABLE_NAMEEditFieldLabel  matlab.ui.control.Label
        EXPORT_TO_WORKSPACECheckBox     matlab.ui.control.CheckBox
        SAVETOEditField                 matlab.ui.control.EditField
        SAVETOEditFieldLabel            matlab.ui.control.Label
        SAVE_TOGGLECheckBox             matlab.ui.control.CheckBox
        PlotTab                         matlab.ui.container.Tab
        closeallfiguresButton           matlab.ui.control.Button
        INTERMEDIATE_PLOTCheckBox       matlab.ui.control.CheckBox
        FIG_HANDLE_VISIBILITYCheckBox   matlab.ui.control.CheckBox
        VerifyTab                       matlab.ui.container.Tab
        FIT_RESULT_VARIABLEEditField    matlab.ui.control.EditField
        FIT_RESULT_VARIABLEEditFieldLabel  matlab.ui.control.Label
        IntermediateResultIDSpinner     matlab.ui.control.Spinner
        IntermediateResultIDSpinnerLabel  matlab.ui.control.Label
        InfoTab                         matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        MoreInfoLabel                   matlab.ui.control.Label
        CompatibilityMATLABR2022aLabel  matlab.ui.control.Label
        RIFFRobustIIRFittingFilteringv20Label  matlab.ui.control.Label
        AuthorNikhilMukundMenonAEIHannoverLabel_2  matlab.ui.control.Label
        AuthorNikhilMukundMenonAEIHannoverLabel  matlab.ui.control.Label
        FilterTab                       matlab.ui.container.Tab
        TargetWitness_FilteredLabel     matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Input_TimeSeriesTextArea_4      matlab.ui.control.TextArea
        Input_TimeSeriesTextArea_2Label_3  matlab.ui.control.Label
        Target_TimeSeriesTextArea       matlab.ui.control.TextArea
        Target_TimeSeriesTextAreaLabel  matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        NFFT_TextArea                   matlab.ui.control.TextArea
        NFFTLabel                       matlab.ui.control.Label
        exportedtoworkspaceLabel        matlab.ui.control.Label
        Input_TimeSeriesTextArea_3      matlab.ui.control.TextArea
        Input_TimeSeriesTextArea_2Label_2  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        FIT_FILTER_STRUCTTextAreaLabel  matlab.ui.control.Label
        FIT_FILTER_STRUCTTextArea       matlab.ui.control.TextArea
        Input_Sampling_FrequencyTextArea_2  matlab.ui.control.TextArea
        Input_FSLabel                   matlab.ui.control.Label
        Witness_TimeSeriesTextArea      matlab.ui.control.TextArea
        Witness_TimeSeriesTextAreaLabel  matlab.ui.control.Label
        FilterTimeSeriesButton          matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        RIFTLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        FIT; % Description
        stopFlag=false; % Description
    end
    
    methods (Access = private)
        
        function  updateListFunc(app)
            
            if ~exist('./measured_TFs','dir')
                mkdir('./measured_TFs')
            end
            
            Files = dir('./measured_TFs');
            File_list = cell(numel(Files)-2,1);
            count = 1;
            for i = 3:numel(Files)
                File_list{count} = Files(i).name;
                count = count+1;
            end
            app.ChooseTFListBox.Items = File_list;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            nodes = [app.rationalfitNode, app.tfestNode, app.invfreqsNode, app.fitmagfrdNode, app.AAANode];
            fittingDefaults = fitTF('defaults');
            fitTF('setup-import',app);
            app.Tree.CheckedNodes = nodes(fittingDefaults.ROUTINE);
            app.ESTIMATE_UNCERTAINITYCheckBox.Value = logical(fittingDefaults.ESTIMATE_UNCERTAINITY);
            
            
            % Add Path to utils
            % addpath('./utils')
            
            %------------Update TextFiles Display----------------------
            
            if ~exist('./measured_TFs','dir')
                mkdir('./measured_TFs')
            end
            
            previousSelection = string(app.ChooseTFListBox.Value);
            app.ChooseTFListBox.Items = {};
            Files = [dir('./measured_TFs/*.txt');dir('./measured_TFs/*.csv')];
            
            if ~isempty(Files)
                File_list = cell(numel(Files),1);
                count = 1;
                for i = 1:numel(Files)
                    File_list{count} = Files(i).name;
                    count = count+1;
                end
                %sort files
                File_list = cellstr(sort(string(File_list))');
                %display
                app.ChooseTFListBox.Items = File_list;
                selectedFiles = intersect(previousSelection,string(File_list),'stable');
                if isempty(selectedFiles), selectedFiles = string(File_list{1}); end
                app.ChooseTFListBox.Value = char(selectedFiles(1));
            end
            
            %------------Update SDF Files Display----------------------
            previousSelection = string(app.ChooseTFListBox_2.Value);
            app.ChooseTFListBox_2.Items = {};
            Files = dir('./measured_TFs/*.DAT');
            if ~isempty(Files)
                File_list = cell(numel(Files),1);
                count = 1;
                for i = 1:numel(Files)
                    File_list{count} = Files(i).name;
                    count = count+1;
                end
                %sort files
                File_list = cellstr(sort(string(File_list))');
                %display
                app.ChooseTFListBox_2.Items = File_list;
                selectedFiles = intersect(previousSelection,string(File_list),'stable');
                if isempty(selectedFiles), selectedFiles = string(File_list{1}); end
                app.ChooseTFListBox_2.Value = cellstr(selectedFiles);
            end


        end

        % Button pushed function: PreviewBodeplotButton
        function PreviewBodeplotButtonPushed(app, event)
            
            %set lamp to busy
            app.Lamp.Color = [1,0,0];
            
            
            if strcmp(app.INPUT_DATA_FORMATDropDown.Value,'TextFile')
                % TextFile
                app.FIT = fitTF("APP",app,"FNAME",fullfile("measured_TFs",app.ChooseTFListBox.Value),'PREVIEW_BODEPLOT_TOGGLE',1);
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'TimeSeries')
                % TimeSeries
                % get variables from 'base' workspace to 'caller' workspace
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "TS1",evalin('base',app.Input_TimeSeriesTextArea.Value{1}),...
                    "TS2",evalin('base',app.Output_TimeSeriesTextArea.Value{1}),...
                    "FS1",evalin('base',app.Input_Sampling_FrequencyTextArea.Value{1}),...
                    "FS2",evalin('base',app.Output_Sampling_FrequencyTextArea.Value{1}),...
                    "BW",evalin('base',app.BWTextArea.Value{1}),'PREVIEW_BODEPLOT_TOGGLE',1);
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'FRD')
                % Frequency Response Data
                % get variables from 'base' workspace to 'caller' workspace
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "FRD",evalin('base',app.FRDTextArea.Value{1}),'PREVIEW_BODEPLOT_TOGGLE',1);
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'F_TF')
                % F & TF
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "F",evalin('base',app.FTextArea.Value{1}),...
                    "TF",evalin('base',app.TFTextArea.Value{1}),'PREVIEW_BODEPLOT_TOGGLE',1);
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'HP_Agilent_Keysight_SDF')
                % HP_Agilent_Keysight_SDF
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "SDF_DATASETS", "./measured_TFs/"+string(app.ChooseTFListBox_2.Value), ...
                    'PREVIEW_BODEPLOT_TOGGLE',1);
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'LTPDA_AO')
                % LTPDA Analysis Object
                % get variables from 'base' workspace to 'caller' workspace
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "AO",evalin('base',app.AOTextArea.Value{1}),'PREVIEW_BODEPLOT_TOGGLE',1);
            end
            
            
            %set lamp to idle
            app.Lamp.Color = [0,1,0];
        end

        % Button pushed function: FITButton
        function FITButtonPushed(app, event)
            
            %set lamp to busy
            app.Lamp.Color = [1,0,0];
            
            if strcmp(app.INPUT_DATA_FORMATDropDown.Value,'TextFile')
                % TextFile
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,"FNAME",fullfile("measured_TFs",app.ChooseTFListBox.Value));
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'TimeSeries')
                % TimeSeries
                % get variables from 'base' workspace to 'caller' workspace
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "TS1",evalin('base',app.Input_TimeSeriesTextArea.Value{1}),...
                    "TS2",evalin('base',app.Output_TimeSeriesTextArea.Value{1}),...
                    "FS1",evalin('base',app.Input_Sampling_FrequencyTextArea.Value{1}),...
                    "FS2",evalin('base',app.Output_Sampling_FrequencyTextArea.Value{1}),...
                    "BW",evalin('base',app.BWTextArea.Value{1}));
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'FRD')
                % Frequency Response Data
                % get variables from 'base' workspace to 'caller' workspace
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "FRD",evalin('base',app.FRDTextArea.Value{1}));
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'F_TF')
                % F & TF
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "F",evalin('base',app.FTextArea.Value{1}),...
                    "TF",evalin('base',app.TFTextArea.Value{1}));
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'HP_Agilent_Keysight_SDF')
                % HP_Agilent_Keysight_SDF
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "SDF_DATASETS", "./measured_TFs/"+string(app.ChooseTFListBox_2.Value));
            elseif strcmp(app.INPUT_DATA_FORMATDropDown.Value,'LTPDA_AO')
                % LTPDA Analysis Object
                % get variables from 'base' workspace to 'caller' workspace
                app.FIT = fitTF('READ_CONFIG',1,'CONFIG_FILE','fitTF_config.ini',"APP",app,...
                    "AO",evalin('base',app.AOTextArea.Value{1}));
            end
            
            if app.EXPORT_TO_WORKSPACECheckBox.Value==1
                if ~isempty(app.FIT)
                % Assign FIT back to Matlab Workspace
                feval(@()assignin('base',app.EXPORT_VARIABLE_NAMEEditField.Value,app.FIT));
                fprintf('Fit result variable exported to workspace as "%s".\n',app.EXPORT_VARIABLE_NAMEEditField.Value);
                end
            end
            
            % reset StopExecutionButton value
            app.StopExecutionButton.Value=0;
            
            %set lamp to idle
            app.Lamp.Color = [0,1,0];
        end

        % Button pushed function: UploadnewfileButton
        function UploadnewfileButtonPushed(app, event)
            [FileName, PathName, FilterIndex] = uigetfile( ...
                {  '*.*','All Files'}, ...
                'Pick a file', ...
                'MultiSelect', 'on');
            drawnow;
            figure(app.UIFigure);
            

            if FilterIndex ~= 0

                if ~iscell(FileName)
                    NewFilePath  = sprintf('%s%s',PathName,FileName);
                    copyfile(NewFilePath,'./measured_TFs/');
                    fprintf('%s copied to ./measured_TFs/ \n',NewFilePath);
                else
                    for ijk = 1:numel(FileName)
                        NewFilePath  = sprintf('%s%s',PathName,FileName{ijk});
                        copyfile(NewFilePath,'./measured_TFs/');
                        fprintf('%s copied to ./measured_TFs/ \n',NewFilePath);
                    end
                end


            end
            
            
            Files = dir('./measured_TFs');
            File_list = cell(numel(Files)-2,1);
            count = 1;
            for i = 3:numel(Files)
                File_list{count} = Files(i).name;
                count = count+1;
            end
            app.ChooseTFListBox.Items = File_list;

            % Click Refresh Button
            app.RefreshButtonPushed;


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            
            % Add Path to utils
            %addpath('./utils')
            
            %------------Update TextFiles Display----------------------
            
            if ~exist('./measured_TFs','dir')
                mkdir('./measured_TFs')
            end
            
            previousSelection = string(app.ChooseTFListBox.Value);
            app.ChooseTFListBox.Items = {};
            Files = [dir('./measured_TFs/*.txt');dir('./measured_TFs/*.csv')];
            
            if ~isempty(Files)
                File_list = cell(numel(Files),1);
                count = 1;
                for i = 1:numel(Files)
                    File_list{count} = Files(i).name;
                    count = count+1;
                end
                %sort files
                File_list = cellstr(sort(string(File_list))');
                %display
                app.ChooseTFListBox.Items = File_list;
                selectedFiles = intersect(previousSelection,string(File_list),'stable');
                if isempty(selectedFiles), selectedFiles = string(File_list{1}); end
                app.ChooseTFListBox.Value = char(selectedFiles(1));
            end
            
            %------------Update SDF Files Display----------------------
            previousSelection = string(app.ChooseTFListBox_2.Value);
            app.ChooseTFListBox_2.Items = {};
            Files = dir('./measured_TFs/*.DAT');
            if ~isempty(Files)
                File_list = cell(numel(Files),1);
                count = 1;
                for i = 1:numel(Files)
                    File_list{count} = Files(i).name;
                    count = count+1;
                end
                %sort files
                File_list = cellstr(sort(string(File_list))');
                %display
                app.ChooseTFListBox_2.Items = File_list;
                selectedFiles = intersect(previousSelection,string(File_list),'stable');
                if isempty(selectedFiles), selectedFiles = string(File_list{1}); end
                app.ChooseTFListBox_2.Value = cellstr(selectedFiles);
            end
            
            % reset StopExecutionButton value
            app.StopExecutionButton.Value=0;
            
            %set lamp to idle
            app.Lamp.Color = [0,1,0];
            
        end

        % Callback function
        function StopExecutionButtonPushed(app, event)
            app.stopFlag=true;
        end

        % Button pushed function: closeallfiguresButton
        function closeallfiguresButtonPushed(app, event)
            close all 
        end

        % Button pushed function: FilterTimeSeriesButton
        function FilterTimeSeriesButtonPushed(app, event)
           fprintf('Performing time domain filtering.\n');

            app.Lamp.Color=[1,0,0];
            
            FIG_TEMP2 = figure('Renderer', 'painters', 'Position', [0 0 0.1 0.1]);    
            
            FIT_FILTER = evalin('base',app.FIT_FILTER_STRUCTTextArea.Value{1});
            WITNESS = evalin('base',app.Witness_TimeSeriesTextArea.Value{1});
            WITNESS = WITNESS(:);

            TARGET = evalin('base',app.Target_TimeSeriesTextArea.Value{1});
            TARGET = TARGET(:);
       
            FS = str2double(app.Input_Sampling_FrequencyTextArea.Value{1});
            WITNESS_FILTERED = time_domain_filtering(FIT_FILTER.ZPK.z,FIT_FILTER.ZPK.p,FIT_FILTER.ZPK.k,FS,WITNESS);            
            TARGET_FILTERED = TARGET - WITNESS_FILTERED;
            
            TimeVec = 1/FS:1/FS:length(WITNESS)/FS;
            NFFT=str2double(app.NFFT_TextArea.Value{1});
            [psd_1,ff_1] = pwelch(WITNESS,hanning(NFFT),NFFT/2,NFFT,FS);
            [psd_2,ff_2] = pwelch(WITNESS_FILTERED,hanning(NFFT),NFFT/2,NFFT,FS);
            [psd_3,ff_3] = pwelch(TARGET,hanning(NFFT),NFFT/2,NFFT,FS);
            [psd_4,ff_4] = pwelch(TARGET_FILTERED,hanning(NFFT),NFFT/2,NFFT,FS);
                      
            
            % Assign WITNESS_FILTERED back to Matlab Workspace
            feval(@()assignin('base',app.Input_TimeSeriesTextArea_3.Value{1},WITNESS_FILTERED));
            fprintf('Filterd output variable exported to workspace as "%s".\n',app.Input_TimeSeriesTextArea_3.Value{1});
            
            % Assign TARGET_FILTERED back to Matlab Workspace
            feval(@()assignin('base',app.Input_TimeSeriesTextArea_4.Value{1},TARGET_FILTERED));
            fprintf('Filterd output variable exported to workspace as "%s".\n',app.Input_TimeSeriesTextArea_4.Value{1});


            clear FIG_HANDLE;
            % persistent FIG_GCF
            FIG_HANDLE.NAME    = 'FILTERING';
            FIG_HANDLE.UNITS = 'Normalized';
            FIG_HANDLE.OUTERPOSITION = [0.04, 0.04, 0.9, 0.9];
            FIG_HANDLE.PAPERORIENTATION = 'PORTRAIT';  %  PORTRAIT or LANDSCAPE
            FIG_GCF = figure(FIG_HANDLE,'Visible',0);
            
            %%%%%%figure(FIG_GCF);
            %FIG_GCA = axes(FIG_GCF);
            FIG_GCA = subplot(2,2,1,'Parent',FIG_GCF);
            plot(FIG_GCA,TimeVec,WITNESS)
            hold on
            plot(FIG_GCA,TimeVec,WITNESS_FILTERED)
            hold off
            title('TimeSeries')
            xlabel('Time [sec]')
            ylabel('Amplitude [V]')
            legend({'Witness','Witness Filtered'})

            
            FIG_GCA = subplot(2,2,2,'Parent',FIG_GCF);
            loglog(FIG_GCA,   ff_1,sqrt(psd_1))
            hold(FIG_GCA,"on")
            loglog(FIG_GCA,ff_2,sqrt(psd_2))
            grid(FIG_GCA,"on")
            hold(FIG_GCA,"off")
            xlabel('Frequency [Hz]')
            ylabel('$\mathrm{V/\sqrt{Hz}}$','interpreter','latex')
            legend({'Original','Filtered'})
            title('Spectrum')
            set(findall(FIG_GCF,'-property','FontSize'),'FontSize',20)
            set(findall(FIG_GCF,'type','line'),'linewidth',2)
            xlim([max(min(ff_2),app.F_INITIALEditField.Value) min(max(ff_2),app.F_FINALEditField.Value)]);


            FIG_GCA = subplot(2,2,3,'Parent',FIG_GCF);
            plot(FIG_GCA,TimeVec,TARGET)
            hold on
            plot(FIG_GCA,TimeVec,TARGET_FILTERED)
            hold off
            title('TimeSeries')
            xlabel('Time [sec]')
            ylabel('Amplitude [V]')
            legend({'Target','Target Filtered'})

            
            FIG_GCA = subplot(2,2,4,'Parent',FIG_GCF);
            loglog(FIG_GCA,   ff_3,sqrt(psd_3))
            hold(FIG_GCA,"on")
            loglog(FIG_GCA,ff_4,sqrt(psd_4))
            grid(FIG_GCA,"on")
            hold(FIG_GCA,"off")
            xlabel('Frequency [Hz]')
            ylabel('$\mathrm{V/\sqrt{Hz}}$','interpreter','latex')
            legend({'Target','Target Filtered'})
            title('Spectrum')
            set(findall(FIG_GCF,'-property','FontSize'),'FontSize',20)
            set(findall(FIG_GCF,'type','line'),'linewidth',2)
            xlim([max(min(ff_4),app.F_INITIALEditField.Value) min(max(ff_4),app.F_FINALEditField.Value)]);


            saveas(FIG_GCF,'temp.png');
            IMG = imread('temp.png');
            imshow(IMG,'Parent',app.UIAxes);
            figure(app.UIFigure);
            
            close(FIG_TEMP2)
            
            app.Lamp.Color=[0,1,0];
        end

        % Button pushed function: UploadnewfileButton_2
        function UploadnewfileButton_2Pushed(app, event)
                        [FileName, PathName, FilterIndex] = uigetfile( ...
                {  '*.*','All Files'}, ...
                'Pick a file', ...
                'MultiSelect', 'on');
            drawnow;
            figure(app.UIFigure);
            
            
            if FilterIndex ~= 0
                if ~iscell(FileName)
                    NewFilePath  = sprintf('%s%s',PathName,FileName);
                    copyfile(NewFilePath,'./measured_TFs/');
                    fprintf('%s copied to ./measured_TFs/ \n',NewFilePath);
                else
                    for ijk = 1:numel(FileName)
                        NewFilePath  = sprintf('%s%s',PathName,FileName{ijk});
                        copyfile(NewFilePath,'./measured_TFs/');
                        fprintf('%s copied to ./measured_TFs/ \n',NewFilePath);
                    end
                end

            end
            
            
            Files = dir('./measured_TFs');
            File_list = cell(numel(Files)-2,1);
            count = 1;
            for i = 3:numel(Files)
                File_list{count} = Files(i).name;
                count = count+1;
            end
            app.ChooseTFListBox.Items = File_list;
                
            % Click Refresh Button
            app.RefreshButtonPushed;


        end

        % Value changed function: MAX_POLESEditField
        function MAX_POLESEditFieldValueChanged(app, event)
            value = app.MAX_POLESEditField.Value;
            app.NUM_ZEROSEditField.Value = value;
        end

        % Value changed function: SMOOTH_LEVELEditField
        function SMOOTH_LEVELEditFieldValueChanged(app, event)
            value = app.SMOOTH_LEVELEditField.Value;
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Selection change function: TabGroup
            % Determine which tab was selected
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox
            switch selectedTab.Title
                case 'Input'
                     app.Lamp_2.Color=[0.94,0.94,0.94];app.Lamp_3.Color=[0.94,0.94,0.94];app.Lamp_4.Color=[0.94,0.94,0.94];app.Lamp_5.Color=[0.94,0.94,0.94];                   
                case 'Freq'
                    app.TabGroup.SelectedTab=app.InputTab;
                    focus(app.PreviewBodeplotButton)
                     app.Lamp_2.Color='blue';app.Lamp_3.Color=[0.94,0.94,0.94];app.Lamp_4.Color=[0.94,0.94,0.94];app.Lamp_5.Color=[0.94,0.94,0.94];                   
                case 'Fitting'
                    app.TabGroup.SelectedTab=app.FreqTab;
                    focus(app.PreviewBodeplotButton)
                    app.Lamp_2.Color='blue';app.Lamp_3.Color='blue';app.Lamp_4.Color=[0.94,0.94,0.94];app.Lamp_5.Color=[0.94,0.94,0.94];                    
                    PreviewBodeplotButtonPushed(app, event);

                case 'Results'
                    app.TabGroup.SelectedTab=app.FittingTab;
                    focus(app.FITButton)
                    app.Lamp_2.Color='blue';app.Lamp_3.Color='blue';app.Lamp_4.Color='blue';app.Lamp_5.Color=[0.94,0.94,0.94];
                    PreviewBodeplotButtonPushed(app, event);

                case 'Plot'
                    app.TabGroup.SelectedTab=app.InputTab;
                case 'Info'
                    app.TabGroup.SelectedTab=app.InputTab;
                case 'Filter'
                    app.TabGroup.SelectedTab=app.InputTab;

            end

        end

        % Value changed function: INPUT_DATA_FORMATDropDown
        function INPUT_DATA_FORMATDropDownValueChanged(app, event)
            value = app.INPUT_DATA_FORMATDropDown.Value;
            if strcmpi(value,'TextFile')
                app.TabGroup2.SelectedTab=app.TextFileTab;
            elseif strcmpi(value,'FRD')
                app.TabGroup2.SelectedTab=app.WorkSpaceTab;
                app.TabGroup3.SelectedTab=app.FRDTab;
            elseif strcmpi(value,'TimeSeries')
                app.TabGroup2.SelectedTab=app.WorkSpaceTab;
                app.TabGroup3.SelectedTab=app.TimeSeriesTab;       
            elseif strcmpi(value,'F_TF')
                app.TabGroup2.SelectedTab=app.WorkSpaceTab;
                app.TabGroup3.SelectedTab=app.FTFTab;
            elseif strcmpi(value,'LTPDA_AO')
                app.TabGroup2.SelectedTab=app.WorkSpaceTab;
                app.TabGroup3.SelectedTab=app.LTPDAAOTab;   
             elseif strcmpi(value,'HP_Agilent_Keysight_SDF')
                app.TabGroup2.SelectedTab=app.SpectrumAnalyzerTab;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Selection change function: TabGroup
            % Determine which tab was selected
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox
            switch selectedTab.Title
                case 'Input'
                    app.Lamp_2.Color='blue';app.Lamp_3.Color=[0.94,0.94,0.94];app.Lamp_4.Color=[0.94,0.94,0.94];app.Lamp_5.Color=[0.94,0.94,0.94];
                    focus(app.PreviewBodeplotButton)
                    PreviewBodeplotButtonPushed(app, event);
                    app.TabGroup.SelectedTab=app.FreqTab;
                case 'Freq'
                    app.Lamp_3.Color='blue';app.Lamp_4.Color=[0.94,0.94,0.94];app.Lamp_5.Color=[0.94,0.94,0.94];
                    %PreviewBodeplotButtonPushed(app, event);
                    app.TabGroup.SelectedTab=app.FittingTab;
                    focus(app.FITButton)

                case 'Fitting'
                    app.Lamp_4.Color='blue';app.Lamp_5.Color=[0.94,0.94,0.94];
                    FITButtonPushed(app, event);
                    app.TabGroup.SelectedTab=app.ResultsTab;
                case 'Results'
                    app.Lamp_5.Color='blue';                   
                case 'Plot'
                    app.TabGroup.SelectedTab=app.InputTab;
                case 'Info'
                    app.TabGroup.SelectedTab=app.InputTab;
                case 'Filter'
                    app.TabGroup.SelectedTab=app.InputTab;
            end

        end

        % Button pushed function: StartWorkflowButton
        function StartWorkflowButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InputTab;      
            focus(app.INPUT_DATA_FORMATDropDown)
            app.Lamp_2.Color=[0.94,0.94,0.94];app.Lamp_3.Color=[0.94,0.94,0.94];app.Lamp_4.Color=[0.94,0.94,0.94];app.Lamp_5.Color=[0.94,0.94,0.94];
        end

        % Value changed function: IntermediateResultIDSpinner
        function IntermediateResultIDSpinnerValueChanged(app, event)
            app.FIT = fitTF('verify',app,app.FIT);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {693, 693};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {426, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.TabGroup2 = uitabgroup(app.InputTab);
            app.TextFileTab = uitab(app.TabGroup2);
            app.TextFileTab.Title = 'TextFile';
            app.ChooseTFListBox = uilistbox(app.TextFileTab);
            app.UploadnewfileButton = uibutton(app.TextFileTab, 'push');
            app.UploadnewfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadnewfileButtonPushed, true);
            app.UploadnewfileButton.Text = 'Upload new file';
            app.FORMATDropDownLabel = uilabel(app.TextFileTab);
            app.FORMATDropDownLabel.Text = 'FORMAT';
            app.FORMATDropDown = uidropdown(app.TextFileTab);
            app.FORMATDropDown.Items = {'f a b', 'w a b', 'f a ib', 'w a ib', 'f a+ib', 'w a+ib'};
            app.FORMATDropDown.Value = 'f a b';
            app.WorkSpaceTab = uitab(app.TabGroup2);
            app.WorkSpaceTab.Title = 'WorkSpace';
            app.TabGroup3 = uitabgroup(app.WorkSpaceTab);
            app.TimeSeriesTab = uitab(app.TabGroup3);
            app.TimeSeriesTab.Title = 'TimeSeries';
            app.Input_TimeSeriesTextAreaLabel = uilabel(app.TimeSeriesTab);
            app.Input_TimeSeriesTextAreaLabel.Text = 'Input_TimeSeries';
            app.Input_TimeSeriesTextArea = uitextarea(app.TimeSeriesTab);
            app.Input_TimeSeriesTextArea.Value = {'TS1'};
            app.SpecifythecorrespondingworkspacevariablenamesLabel = uilabel(app.TimeSeriesTab);
            app.SpecifythecorrespondingworkspacevariablenamesLabel.Text = 'Specify the corresponding workspace variable names';
            app.Output_TimeSeriesTextAreaLabel = uilabel(app.TimeSeriesTab);
            app.Output_TimeSeriesTextAreaLabel.Text = 'Output_TimeSeries';
            app.Output_TimeSeriesTextArea = uitextarea(app.TimeSeriesTab);
            app.Output_TimeSeriesTextArea.Value = {'TS2'};
            app.Input_Sampling_FrequencyTextAreaLabel = uilabel(app.TimeSeriesTab);
            app.Input_Sampling_FrequencyTextAreaLabel.Text = 'Input_Sampling_Frequency';
            app.Input_Sampling_FrequencyTextArea = uitextarea(app.TimeSeriesTab);
            app.Input_Sampling_FrequencyTextArea.Value = {'16384'};
            app.Output_Sampling_FrequencyTextAreaLabel = uilabel(app.TimeSeriesTab);
            app.Output_Sampling_FrequencyTextAreaLabel.Text = 'Output_Sampling_Frequency';
            app.Output_Sampling_FrequencyTextArea = uitextarea(app.TimeSeriesTab);
            app.Output_Sampling_FrequencyTextArea.Value = {'16384'};
            app.BWTextAreaLabel = uilabel(app.TimeSeriesTab);
            app.BWTextAreaLabel.Text = 'BW';
            app.BWTextArea = uitextarea(app.TimeSeriesTab);
            app.BWTextArea.Value = {'0.1'};
            app.FRDTab = uitab(app.TabGroup3);
            app.FRDTab.Title = 'FRD';
            app.SpecifythecorrespondingworkspacevariablenameLabel = uilabel(app.FRDTab);
            app.SpecifythecorrespondingworkspacevariablenameLabel.Text = 'Specify the corresponding workspace variable name';
            app.FRDTextAreaLabel = uilabel(app.FRDTab);
            app.FRDTextAreaLabel.Text = 'FRD';
            app.FRDTextArea = uitextarea(app.FRDTab);
            app.FRDTextArea.Value = {'FRD'};
            app.MATLABFrequencyResponseDataLabel = uilabel(app.FRDTab);
            app.MATLABFrequencyResponseDataLabel.Text = 'MATLAB Frequency Response Data';
            app.FTFTab = uitab(app.TabGroup3);
            app.FTFTab.Title = 'F & TF';
            app.SpecifythecorrespondingworkspacevariablenamesLabel_2 = uilabel(app.FTFTab);
            app.SpecifythecorrespondingworkspacevariablenamesLabel_2.Text = 'Specify the corresponding workspace variable names';
            app.FTextAreaLabel = uilabel(app.FTFTab);
            app.FTextAreaLabel.Text = 'F';
            app.FTextArea = uitextarea(app.FTFTab);
            app.FTextArea.Value = {'F'};
            app.TFTextAreaLabel = uilabel(app.FTFTab);
            app.TFTextAreaLabel.Text = 'TF';
            app.TFTextArea = uitextarea(app.FTFTab);
            app.TFTextArea.Value = {'TF'};
            app.FrequencyHzComplexTransferFunctionLabel = uilabel(app.FTFTab);
            app.FrequencyHzComplexTransferFunctionLabel.Text = 'Frequency (Hz) & Complex Transfer Function';
            app.LTPDAAOTab = uitab(app.TabGroup3);
            app.LTPDAAOTab.Title = 'LTPDA AO';
            app.AOTextAreaLabel = uilabel(app.LTPDAAOTab);
            app.AOTextAreaLabel.Text = 'AO';
            app.AOTextArea = uitextarea(app.LTPDAAOTab);
            app.AOTextArea.Value = {'AO'};
            app.SpecifythecorrespondingworkspacevariablenameLabel_2 = uilabel(app.LTPDAAOTab);
            app.SpecifythecorrespondingworkspacevariablenameLabel_2.Text = 'Specify the corresponding workspace variable name';
            app.LTPDAAnalysisObjectLabel = uilabel(app.LTPDAAOTab);
            app.LTPDAAnalysisObjectLabel.Text = 'LTPDA Analysis Object ';
            app.SpectrumAnalyzerTab = uitab(app.TabGroup2);
            app.SpectrumAnalyzerTab.Title = 'SpectrumAnalyzer';
            app.UploadnewfileButton_2 = uibutton(app.SpectrumAnalyzerTab, 'push');
            app.UploadnewfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadnewfileButton_2Pushed, true);
            app.UploadnewfileButton_2.Text = 'Upload new file';
            app.ChooseTFListBox_2 = uilistbox(app.SpectrumAnalyzerTab);
            app.ChooseTFListBox_2.Multiselect = 'on';
            app.ChooseTFListBox_2.Value = {'Item 1'};
            app.HPAgilentKeysightSDFDATformatLabel = uilabel(app.SpectrumAnalyzerTab);
            app.HPAgilentKeysightSDFDATformatLabel.Text = 'HP/Agilent/Keysight SDF (.DAT) format';
            app.IfmeasurementsspanacrossdifferentfilesusemultiselectLabel = uilabel(app.SpectrumAnalyzerTab);
            app.IfmeasurementsspanacrossdifferentfilesusemultiselectLabel.Text = 'If measurements span across different files (with overlapping frequencies), use multi-select.';
            app.INPUT_DATA_FORMATDropDownLabel = uilabel(app.InputTab);
            app.INPUT_DATA_FORMATDropDownLabel.Text = 'INPUT_DATA_FORMAT';
            app.INPUT_DATA_FORMATDropDown = uidropdown(app.InputTab);
            app.INPUT_DATA_FORMATDropDown.Items = {'TextFile', 'FRD', 'TimeSeries', 'F_TF', 'HP_Agilent_Keysight_SDF', 'LTPDA_AO'};
            app.INPUT_DATA_FORMATDropDown.ValueChangedFcn = createCallbackFcn(app, @INPUT_DATA_FORMATDropDownValueChanged, true);
            app.INPUT_DATA_FORMATDropDown.Value = 'TextFile';
            app.FreqTab = uitab(app.TabGroup);
            app.FreqTab.Title = 'Freq';
            app.F_INITIALEditField = uieditfield(app.FreqTab, 'numeric');
            app.F_INITIALEditField.Value = 0.1;
            app.F_INITIALEditFieldLabel = uilabel(app.FreqTab);
            app.F_INITIALEditFieldLabel.Text = 'F_INITIAL';
            app.F_INITIAL_toggleCheckBox = uicheckbox(app.FreqTab);
            app.F_INITIAL_toggleCheckBox.Text = 'F_INITIAL_toggle';
            app.F_FINAL_toggleCheckBox = uicheckbox(app.FreqTab);
            app.F_FINAL_toggleCheckBox.Text = 'F_FINAL_toggle';
            app.F_FINALEditField = uieditfield(app.FreqTab, 'numeric');
            app.F_FINALEditField.Value = 8192;
            app.F_FINALEditFieldLabel = uilabel(app.FreqTab);
            app.F_FINALEditFieldLabel.Text = 'F_FINAL';
            app.FIT_NOISE_FLOORCheckBox = uicheckbox(app.FreqTab);
            app.FIT_NOISE_FLOORCheckBox.Text = 'FIT_NOISE_FLOOR';
            app.SMOOTH_LEVELEditFieldLabel = uilabel(app.FreqTab);
            app.SMOOTH_LEVELEditFieldLabel.Text = 'SMOOTH_LEVEL';
            app.SMOOTH_LEVELEditField = uieditfield(app.FreqTab, 'numeric');
            app.SMOOTH_LEVELEditField.ValueChangedFcn = createCallbackFcn(app, @SMOOTH_LEVELEditFieldValueChanged, true);
            app.SMOOTH_LEVELEditField.Value = 7;
            app.HzLabel = uilabel(app.FreqTab);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.FreqTab);
            app.HzLabel_2.Text = 'Hz';
            app.Label = uilabel(app.FreqTab);
            app.Label.Text = '(0-7)';
            app.SMOOTH_WIN_LENSliderLabel = uilabel(app.FreqTab);
            app.SMOOTH_WIN_LENSliderLabel.Text = 'SMOOTH_WIN_LEN';
            app.SMOOTH_WIN_LENSlider = uislider(app.FreqTab);
            app.MethodLabel = uilabel(app.FreqTab);
            app.MethodLabel.Text = 'Method';
            app.whenMethod7Label = uilabel(app.FreqTab);
            app.whenMethod7Label.Text = '(when Method=7)';
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.OPTIMIZERDropDown = uidropdown(app.FittingTab);
            app.OPTIMIZERDropDown.Items = {'FMINCON', 'SURROGATE', 'BADS'};
            app.OPTIMIZERDropDown.Value = 'SURROGATE';
            app.OPTIMIZERDropDownLabel = uilabel(app.FittingTab);
            app.OPTIMIZERDropDownLabel.Text = 'OPTIMIZER';
            app.ATTAIN_GOFCheckBox = uicheckbox(app.FittingTab);
            app.ATTAIN_GOFCheckBox.Text = 'ATTAIN_GOF';
            app.MIN_POLESEditFieldLabel = uilabel(app.FittingTab);
            app.MIN_POLESEditFieldLabel.Text = 'MIN_POLES';
            app.MIN_POLESEditField = uieditfield(app.FittingTab, 'numeric');
            app.MIN_POLESEditField.Limits = [0 Inf];
            app.MIN_POLESEditField.Value = 1;
            app.MAX_POLESEditFieldLabel = uilabel(app.FittingTab);
            app.MAX_POLESEditFieldLabel.Text = 'MAX_POLES';
            app.MAX_POLESEditField = uieditfield(app.FittingTab, 'numeric');
            app.MAX_POLESEditField.Limits = [1 Inf];
            app.MAX_POLESEditField.ValueChangedFcn = createCallbackFcn(app, @MAX_POLESEditFieldValueChanged, true);
            app.MAX_POLESEditField.Value = 20;
            app.NUM_TRIALSEditFieldLabel = uilabel(app.FittingTab);
            app.NUM_TRIALSEditFieldLabel.Text = 'NUM_TRIALS';
            app.NUM_TRIALSEditField = uieditfield(app.FittingTab, 'numeric');
            app.NUM_TRIALSEditField.Value = 10;
            app.DESIRED_GOFEditFieldLabel = uilabel(app.FittingTab);
            app.DESIRED_GOFEditFieldLabel.Text = 'DESIRED_GOF';
            app.DESIRED_GOFEditField = uieditfield(app.FittingTab, 'numeric');
            app.DESIRED_GOFEditField.Value = 80;
            app.ESTIMATE_UNCERTAINITYCheckBox = uicheckbox(app.FittingTab);
            app.ESTIMATE_UNCERTAINITYCheckBox.Text = 'ESTIMATE_UNCERTAINITY';
            app.ENFORCE_STABILITYCheckBox = uicheckbox(app.FittingTab);
            app.ENFORCE_STABILITYCheckBox.Text = 'ENFORCE_STABILITY';
            app.ENFORCE_STABILITYCheckBox.Value = true;
            app.Label_2 = uilabel(app.FittingTab);
            app.Label_2.Text = '%';
            app.ENFORCE_MINIMUM_PHASECheckBox = uicheckbox(app.FittingTab);
            app.ENFORCE_MINIMUM_PHASECheckBox.Text = 'ENFORCE_MINIMUM_PHASE';
            app.NUM_ZEROSEditFieldLabel = uilabel(app.FittingTab);
            app.NUM_ZEROSEditFieldLabel.Text = 'NUM_ZEROS';
            app.NUM_ZEROSEditField = uieditfield(app.FittingTab, 'numeric');
            app.NUM_ZEROSEditField.Limits = [0 Inf];
            app.NUM_ZEROSEditField.Value = 19;
            app.FIT_WEIGHT_FROM_PHASE_SMOOTHNESSCheckBox = uicheckbox(app.FittingTab);
            app.FIT_WEIGHT_FROM_PHASE_SMOOTHNESSCheckBox.Text = 'FIT_WEIGHT_FROM_PHASE_SMOOTHNESS';
            app.FIT_WEIGHT_FROM_PHASE_SMOOTHNESSCheckBox.Value = true;
            app.INCLUDE_IODELAYCheckBox = uicheckbox(app.FittingTab);
            app.INCLUDE_IODELAYCheckBox.Text = 'INCLUDE_IODELAY';
            app.Tree = uitree(app.FittingTab, 'checkbox');
            app.FITTING_ROUTINESNode = uitreenode(app.Tree);
            app.FITTING_ROUTINESNode.Text = 'FITTING_ROUTINES';
            app.rationalfitNode = uitreenode(app.FITTING_ROUTINESNode);
            app.rationalfitNode.Text = 'rationalfit';
            app.tfestNode = uitreenode(app.FITTING_ROUTINESNode);
            app.tfestNode.Text = 'tfest';
            app.invfreqsNode = uitreenode(app.FITTING_ROUTINESNode);
            app.invfreqsNode.Text = 'invfreqs';
            app.fitmagfrdNode = uitreenode(app.FITTING_ROUTINESNode);
            app.fitmagfrdNode.Text = 'fitmagfrd';
            app.AAANode = uitreenode(app.FITTING_ROUTINESNode);
            app.AAANode.Text = 'AAA';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.rationalfitNode, app.tfestNode];
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SAVE_TOGGLECheckBox = uicheckbox(app.ResultsTab);
            app.SAVE_TOGGLECheckBox.Text = 'SAVE_TOGGLE';
            app.SAVETOEditFieldLabel = uilabel(app.ResultsTab);
            app.SAVETOEditFieldLabel.Text = 'SAVETO';
            app.SAVETOEditField = uieditfield(app.ResultsTab, 'text');
            app.SAVETOEditField.Value = 'Saved_Models';
            app.EXPORT_TO_WORKSPACECheckBox = uicheckbox(app.ResultsTab);
            app.EXPORT_TO_WORKSPACECheckBox.Text = 'EXPORT_TO_WORKSPACE';
            app.EXPORT_TO_WORKSPACECheckBox.Value = true;
            app.EXPORT_VARIABLE_NAMEEditFieldLabel = uilabel(app.ResultsTab);
            app.EXPORT_VARIABLE_NAMEEditFieldLabel.Text = 'EXPORT_VARIABLE_NAME';
            app.EXPORT_VARIABLE_NAMEEditField = uieditfield(app.ResultsTab, 'text');
            app.EXPORT_VARIABLE_NAMEEditField.Value = 'FIT_RESULT';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.FIG_HANDLE_VISIBILITYCheckBox = uicheckbox(app.PlotTab);
            app.FIG_HANDLE_VISIBILITYCheckBox.Text = 'FIG_HANDLE_VISIBILITY';
            app.FIG_HANDLE_VISIBILITYCheckBox.Value = false;
            app.INTERMEDIATE_PLOTCheckBox = uicheckbox(app.PlotTab);
            app.INTERMEDIATE_PLOTCheckBox.Text = 'INTERMEDIATE_PLOT';
            app.INTERMEDIATE_PLOTCheckBox.Value = true;
            app.closeallfiguresButton = uibutton(app.PlotTab, 'push');
            app.closeallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @closeallfiguresButtonPushed, true);
            app.closeallfiguresButton.Text = 'close all figures';
            app.VerifyTab = uitab(app.TabGroup);
            app.VerifyTab.Title = 'Verify';
            app.IntermediateResultIDSpinnerLabel = uilabel(app.VerifyTab);
            app.IntermediateResultIDSpinnerLabel.Text = 'Intermediate Result ID';
            app.IntermediateResultIDSpinner = uispinner(app.VerifyTab);
            app.IntermediateResultIDSpinner.Limits = [0 Inf];
            app.IntermediateResultIDSpinner.RoundFractionalValues = 'on';
            app.IntermediateResultIDSpinner.ValueChangedFcn = createCallbackFcn(app, @IntermediateResultIDSpinnerValueChanged, true);
            app.FIT_RESULT_VARIABLEEditFieldLabel = uilabel(app.VerifyTab);
            app.FIT_RESULT_VARIABLEEditFieldLabel.Text = 'FIT_RESULT _VARIABLE';
            app.FIT_RESULT_VARIABLEEditField = uieditfield(app.VerifyTab, 'text');
            app.FIT_RESULT_VARIABLEEditField.Value = 'FIT_RESULT';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.AuthorNikhilMukundMenonAEIHannoverLabel = uilabel(app.InfoTab);
            app.AuthorNikhilMukundMenonAEIHannoverLabel.Text = 'Author:  Nikhil Mukund Menon (AEI Hannover)';
            app.AuthorNikhilMukundMenonAEIHannoverLabel_2 = uilabel(app.InfoTab);
            app.AuthorNikhilMukundMenonAEIHannoverLabel_2.Text = 'Description: Generate robust IIR filter models for the measured transfer functions.';
            app.RIFFRobustIIRFittingFilteringv20Label = uilabel(app.InfoTab);
            app.RIFFRobustIIRFittingFilteringv20Label.Text = 'RIFF: Robust IIR Fitting & Filtering v4.1';
            app.CompatibilityMATLABR2022aLabel = uilabel(app.InfoTab);
            app.CompatibilityMATLABR2022aLabel.Text = 'Compatibility: MATLAB R2026b+';
            app.MoreInfoLabel = uilabel(app.InfoTab);
            app.MoreInfoLabel.Text = 'More Info: ';
            app.Hyperlink = uihyperlink(app.InfoTab);
            app.Hyperlink.URL = 'github.com/Nikhil-Mukund/RIFF';
            app.Hyperlink.Text = 'github.com/Nikhil-Mukund/RIFF';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.FilterTimeSeriesButton = uibutton(app.FilterTab, 'push');
            app.FilterTimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterTimeSeriesButtonPushed, true);
            app.FilterTimeSeriesButton.Text = 'FilterTimeSeries';
            app.Witness_TimeSeriesTextAreaLabel = uilabel(app.FilterTab);
            app.Witness_TimeSeriesTextAreaLabel.Text = 'Witness_TimeSeries';
            app.Witness_TimeSeriesTextArea = uitextarea(app.FilterTab);
            app.Witness_TimeSeriesTextArea.Value = {'TS1'};
            app.Input_FSLabel = uilabel(app.FilterTab);
            app.Input_FSLabel.Text = 'Input_FS';
            app.Input_Sampling_FrequencyTextArea_2 = uitextarea(app.FilterTab);
            app.Input_Sampling_FrequencyTextArea_2.Value = {'16384'};
            app.FIT_FILTER_STRUCTTextArea = uitextarea(app.FilterTab);
            app.FIT_FILTER_STRUCTTextArea.Value = {'FIT_RESULT'};
            app.FIT_FILTER_STRUCTTextAreaLabel = uilabel(app.FilterTab);
            app.FIT_FILTER_STRUCTTextAreaLabel.Text = 'FIT_FILTER_STRUCT';
            app.Label_3 = uilabel(app.FilterTab);
            app.Label_3.Text = '-----------------------------------------------------';
            app.Input_TimeSeriesTextArea_2Label_2 = uilabel(app.FilterTab);
            app.Input_TimeSeriesTextArea_2Label_2.Text = 'Witness_TimeSeries_Filtered';
            app.Input_TimeSeriesTextArea_3 = uitextarea(app.FilterTab);
            app.Input_TimeSeriesTextArea_3.Value = {'Witness_Filtered'};
            app.exportedtoworkspaceLabel = uilabel(app.FilterTab);
            app.exportedtoworkspaceLabel.Text = '(exported to workspace)';
            app.NFFTLabel = uilabel(app.FilterTab);
            app.NFFTLabel.Text = 'NFFT';
            app.NFFT_TextArea = uitextarea(app.FilterTab);
            app.NFFT_TextArea.Value = {'16384'};
            app.HzLabel_3 = uilabel(app.FilterTab);
            app.HzLabel_3.Text = 'Hz';
            app.Target_TimeSeriesTextAreaLabel = uilabel(app.FilterTab);
            app.Target_TimeSeriesTextAreaLabel.Text = 'Target_TimeSeries';
            app.Target_TimeSeriesTextArea = uitextarea(app.FilterTab);
            app.Target_TimeSeriesTextArea.Value = {'TS2'};
            app.Input_TimeSeriesTextArea_2Label_3 = uilabel(app.FilterTab);
            app.Input_TimeSeriesTextArea_2Label_3.Text = 'Target_TimeSeries_Filtered';
            app.Input_TimeSeriesTextArea_4 = uitextarea(app.FilterTab);
            app.Input_TimeSeriesTextArea_4.Value = {'Target_Filtered'};
            app.Label_4 = uilabel(app.FilterTab);
            app.Label_4.Text = '-----------------------------------------------------';
            app.TargetWitness_FilteredLabel = uilabel(app.FilterTab);
            app.TargetWitness_FilteredLabel.Text = '(Target - Witness_Filtered)';
            app.FITButton = uibutton(app.LeftPanel, 'push');
            app.FITButton.ButtonPushedFcn = createCallbackFcn(app, @FITButtonPushed, true);
            app.FITButton.Text = 'FIT';
            app.PreviewBodeplotButton = uibutton(app.LeftPanel, 'push');
            app.PreviewBodeplotButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewBodeplotButtonPushed, true);
            app.PreviewBodeplotButton.Text = 'Preview Bodeplot';
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.StopExecutionButton = uibutton(app.LeftPanel, 'state');
            app.StopExecutionButton.Text = 'Stop Execution';
            app.BackButton = uibutton(app.LeftPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Lamp_2 = uilamp(app.LeftPanel);
            app.Lamp_3 = uilamp(app.LeftPanel);
            app.Lamp_4 = uilamp(app.LeftPanel);
            app.Lamp_5 = uilamp(app.LeftPanel);
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '---------------------';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '---------------------';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '---------------------';
            app.StartWorkflowButton = uibutton(app.LeftPanel, 'push');
            app.StartWorkflowButton.ButtonPushedFcn = createCallbackFcn(app, @StartWorkflowButtonPushed, true);
            app.StartWorkflowButton.Text = 'Start Workflow';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RIFTLabel = uilabel(app.RightPanel);
            app.RIFTLabel.Text = 'RIFF v4.1';
            app.Lamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RIFF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end